classdef coneMosaicWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        coneMosaicWindow               matlab.ui.Figure
        menuFile                       matlab.ui.container.Menu
        menuFileRefresh                matlab.ui.container.Menu
        menuFileClose                  matlab.ui.container.Menu
        menuEdit                       matlab.ui.container.Menu
        menuEditName                   matlab.ui.container.Menu
        menuEditClearData              matlab.ui.container.Menu
        menuEditFontSize               matlab.ui.container.Menu
        menuPlot                       matlab.ui.container.Menu
        menuPlotMacular                matlab.ui.container.Menu
        menuPlotMacularTransmittance   matlab.ui.container.Menu
        menuPlotMacularAbsorptance     matlab.ui.container.Menu
        menuPlotMacularAbsorbance      matlab.ui.container.Menu
        menuPlotConeAbsorptance        matlab.ui.container.Menu
        menuPlotMosaic                 matlab.ui.container.Menu
        menuPlotMosaicConeMosaic       matlab.ui.container.Menu
        menuPlotMosaicMeanAbsorptions  matlab.ui.container.Menu
        menuPlotMosaicMeanCurrent      matlab.ui.container.Menu
        menuPlotEMPath                 matlab.ui.container.Menu
        menuPlotHLine                  matlab.ui.container.Menu
        menuPlotVLine                  matlab.ui.container.Menu
        menuPlotHLineLMS               matlab.ui.container.Menu
        menuPlotVLineLMS               matlab.ui.container.Menu
        menuPlotTimeSeries             matlab.ui.container.Menu
        menuCones                      matlab.ui.container.Menu
        menuConesGenerateEM            matlab.ui.container.Menu
        menuConesAbsorptions           matlab.ui.container.Menu
        menuConesPhotocurrent          matlab.ui.container.Menu
        menuConePhotocurrentNoise      matlab.ui.container.Menu
        menuHelp                       matlab.ui.container.Menu
        menuAppNotes                   matlab.ui.container.Menu
        txtConsistency                 matlab.ui.control.Label
        txtMosaic                      matlab.ui.control.Label
        txtOS                          matlab.ui.control.Label
        MosaicpropertiesPanel          matlab.ui.container.Panel
        editRows                       matlab.ui.control.EditField
        editCols                       matlab.ui.control.EditField
        txtMosaicSize                  matlab.ui.control.Label
        editExpTime                    matlab.ui.control.EditField
        txtExposureUnits               matlab.ui.control.Label
        txtIntegration                 matlab.ui.control.Label
        txtEccentricity                matlab.ui.control.Label
        txtKLMSRatio                   matlab.ui.control.Label
        editKLMS                       matlab.ui.control.EditField
        editEccentricity               matlab.ui.control.EditField
        RenderPanel                    matlab.ui.container.Panel
        editGam                        matlab.ui.control.EditField
        txtDisplayGamma                matlab.ui.control.Label
        btnComputeImage                matlab.ui.control.Button
        MosaicnamePanel                matlab.ui.container.Panel
        txtMessage                     matlab.ui.control.Label
        popupImageType                 matlab.ui.control.DropDown
        axes2                          matlab.ui.control.UIAxes
        ConepropertiesPanel            matlab.ui.container.Panel
        text46                         matlab.ui.control.Label
        editConeWidth                  matlab.ui.control.EditField
        editConeHeight                 matlab.ui.control.EditField
        textConePigmentDensity         matlab.ui.control.Label
        editConeOpticalDensity         matlab.ui.control.EditField
        textConePeakEfficiency         matlab.ui.control.Label
        editConePeakEfficiency         matlab.ui.control.EditField
        textMacularDensity             matlab.ui.control.Label
        editMacularDensity             matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function coneMosaicGUIRefresh(app, ~, ~, handles, plotType)
            % (File | Refresh) Update the Cone mosaic window interface - main pulldown
            %
            % Syntax:
            %   coneMosaicGUIRefresh(handles)
            %
            % Description:
            %    Update the cone mosaic window's GU interface, via the main pulldown.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % History:
            %    xx/xx/16  HJ, BW  ISETBIO TEAM, 2016
            %    02/08/18  jnm     Formatting
            
            % get coneMosaic object
            cm = handles.cMosaic;
            
            % Place name in text string box
            set(handles.mosaicName, 'string', sprintf('%s', cm.name));
            
            % set row and cols
            set(handles.editRows, 'string', num2str(cm.rows));
            set(handles.editCols, 'string', num2str(cm.cols));
            
            % set integration time
            set(handles.editExpTime, 'string', ...
                sprintf('%.1f', cm.integrationTime * 1e3));
            
            % set KLMS ratio
            str = sprintf('[%.1f, %.1f, %.1f, %.1f]', cm.spatialDensity(1), ...
                cm.spatialDensity(2), cm.spatialDensity(3), cm.spatialDensity(4));
            set(handles.editKLMS, 'string', str);
            
            % set description strings
            str = cm.description('skipMacular', true, 'skipPigment', true);
            set(handles.txtMosaic, 'string', str);
            
            % set outersegment description
            str = cm.descriptionOS;
            set(handles.txtOS, 'string', str);
            
            % set photopigment properties
            set(handles.editConeWidth, 'string', num2str(cm.pigment.width * 1e6));
            set(handles.editConeHeight, 'string', num2str(cm.pigment.height * 1e6));
            
            str = sprintf('[%.1f, %.1f, %.1f]', cm.pigment.opticalDensity(1), ...
                cm.pigment.opticalDensity(2), cm.pigment.opticalDensity(3));
            set(handles.editConeOpticalDensity, 'string', str);
            
            str = sprintf('[%.2f, %.2f, %.2f]', cm.pigment.peakEfficiency(1), ...
                cm.pigment.peakEfficiency(2), cm.pigment.peakEfficiency(3));
            set(handles.editConePeakEfficiency, 'string', str);
            
            % set macular density
            set(handles.editMacularDensity, 'string', num2str(cm.macular.density));
            
            % Set eccentricity in the window based on the center. This is specified in
            % meters, and we convert it to deg for the window.
            ecc = sqrt(sum(cm.center .^ 2));   % Meters
            
            % Why don't we have a builtin variable for this?  Or a way to compute it?
            % I think we do ... help! (BW)
            deg2m = 3.3333e-04;
            ecc = ecc / deg2m;
            set(handles.editEccentricity, 'string', num2str(ecc, 2));
            
            % check if absorptions and current are available
            if isempty(cm.absorptions)
                set(handles.menuPlotMosaicMeanAbsorptions, 'Enable', 'off');
            else
                set(handles.menuPlotMosaicMeanAbsorptions, 'Enable', 'on');
            end
            
            if isempty(cm.current)
                set(handles.menuPlotMosaicMeanCurrent, 'Enable', 'off');
            else
                set(handles.menuPlotMosaicMeanCurrent, 'Enable', 'on');
            end
            
            %% Set the strings in the popup menu for what to plot
            str = {'Cone mosaic'};
            
            % Add absorption options
            if ~isempty(cm.absorptions)
                str = [str {'Mean absorptions', 'Absorption movie'}];
            end
            
            % Add photocurrent options
            if ~isempty(cm.current)
                str = [str {'Mean photocurrent', 'Photocurrent movie'}];
            end
            
            if ~exist('plotType','var')
                % The user did not specify the plot type.  So we get it from the
                % window.
                index = get(handles.popupImageType, 'Value');
                if index > length(str), index = 1; end  % Cone mosaic
                plotType = str{index};
            else
                plotType = ieParamFormat(plotType);
                tmp = cell(size(str));
                for ii=1:numel(str), tmp{ii} = ieParamFormat(str{ii}); end
                % The user did specify plotType.  Find it and use it
                [~,index] = ismember(plotType,tmp);
            end
            
            % Set the index and then string in the window popup menu
            set(handles.popupImageType, 'Value', index);
            set(handles.popupImageType, 'String', str);
            
            %% Here are the different plotting options for the main axis (handles.axes2)
            
            % Gamma is managed within plot for the mean images. For the video, we are
            % handling it this way so we can, in the future, set additional parameters
            % for the movies that get passed to ieMovie.
            g = str2num(get(handles.editGam, 'string'));
            
            switch ieParamFormat(plotType)
                case 'conemosaic'
                    % cone mosaic image
                    % TODO:  For large mosaics, the computation is slow. We should
                    % compute it once and store it.
                    nCones  = size(cm.coneLocs, 1);
                    maxCones = 1e4;
                    if  nCones > maxCones
                        ieInWindowMessage('Initializing large mosaic image', handles);
                    end
                    cm.plot('cone mosaic', 'hf', handles.axes2);
                    ieInWindowMessage('', handles)
            
                case 'meanabsorptions'
                    % mean cone absorptions
                    cm.plot('mean absorptions', 'hf', handles.axes2);
            
                case 'absorptionmovie'
                    ieInWindowMessage('Showing absorption movie', handles)
                    cm.plot('movie absorptions', 'hf', handles.axes2, ...
                        'gamma', g);
                    % Saved the movie data in the axis as userdata.data
                    ieInWindowMessage('', handles)
            
                case 'meanphotocurrent'
                    cm.plot('mean current', 'hf', handles.axes2);
            
                case 'photocurrentmovie'
                    ieInWindowMessage('Showing photocurrent movie', handles)
                    cm.plot('movie current', 'hf', handles.axes2, 'gamma', g);
                    ieInWindowMessage('', handles)
            
                otherwise
                    error('Unknown plot type');
            end
            
            enable.hLine = 'on';
            enable.vLine = 'on';
            enable.hLineLMS = 'on';
            enable.vLineLMS = 'on';
            enable.timeSeries = 'on';
            contextMenuInit(app, handles);
            contextMenuEnable(app, handles, enable);
        end
        
        function contextMenuEnable(app, handles, enable)
            % Set and/or enable plot options in menu
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Set the enable plot options in the menu. Enable is a structure
            %    containing 'on' and 'off' values.
            %
            % Inputs:
            %    handles - Structure with handles and user data (see GUIDATA)
            %    enable  - A structure containing 'on' and 'off' values in reference to
            %              the menu options.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % enable is a structure of 'on' and 'off' values
            set(handles.menuPlotHLine, 'Enable', enable.hLine);
            set(handles.menuPlotVLine, 'Enable', enable.vLine);
            set(handles.menuPlotHLineLMS, 'Enable', enable.hLineLMS);
            set(handles.menuPlotVLineLMS, 'Enable', enable.vLineLMS);
            set(handles.menuPlotTimeSeries, 'Enable', enable.timeSeries);
        end
        
        function c = contextMenuInit(app, handles)
            % Set up the context (right click) menu.
            %
            % Syntax:
            %   c = contextMenuInit(handles)
            %
            % Description:
            %    Set up the context (right click) menu.
            %
            % Inputs:
            %    handles - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    c       - The created context menu
            %
            % Optional key/value pairs:
            %    None.
            %
            
            c = uicontextmenu;
            if ~isempty(handles.axes2.Children)
                for ichild = 1:size(handles.axes2.Children, 1)
                    handles.axes2.Children(ichild).UIContextMenu = c;
                end
                uimenu(c, 'Label', 'hLine response', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'vLine response', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'hLine LMS', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'vLine LMS', 'Callback', @app.contextMenuPlot);
                uimenu(c, 'Label', 'time series', 'Callback', @app.contextMenuPlot);
            end
        end
        
        function contextMenuPlot(app, source, callbackdata)
            % Callback function for five of the context menu plots
            %
            % Syntax:
            %   contextMenuPlot(source, callbackdata)
            %
            % Description:
            %    Callback function for the following five context menu plots:
            %       hline, vline, hLineLMS, vLineLMS, timeSeries
            %
            %    Data may cover absorptions or current for any of the five
            %    aforementioned plots.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    source       - The structure with handles and user data (see GUIDATA),
            %                   also known as handles in other functions.
            %    callbackdata - Data for callback
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % The guidata return of the variable 'source' contains the gui objects
            handles = guidata(source);
            
            % determine which data to use (absorption or current)
            contents = get(handles.popupImageType, 'String');
            index = get(handles.popupImageType, 'Value');
            if index > length(contents), index = 1; end
            plotType = contents{index};
            
            % Identify the data type, absorptions or current
            switch plotType
                case {'Mean absorptions', 'Cone mosaic', 'Absorption movie'}
                    dataType = 'absorptions';
                case {'Mean photocurrent', 'Photocurrent movie'}
                    dataType = 'current';
            end
            
            % Figure out which plot was requested and build the command
            switch ieParamFormat(source.Label)
                case 'hlineresponse'
                    cmd = ['hline', dataType];
                case 'vlineresponse'
                    cmd = ['vline', dataType];
                case 'hlinelms'
                    cmd = ['hline', dataType, 'lms'];
                case 'vlinelms'
                    cmd = ['vline', dataType, 'lms'];
                case 'timeseries'
                    cmd = ['time series', dataType];
                otherwise
                    error('Unknown plot type %s\n', source.label);
            end
            
            % Call the plot command, setting the main window axis for the first place
            % to start.
            handles.cMosaic.plot(cmd, 'hf', handles.axes2);
        end
        
        function menuAnComputeFromOI_Callback(app, hObject, eventdata, handles)
            % (Compute | Absorptions) Compute absorptions from OI in database
            %
            % Syntax:
            %   menuAnComputeFromOI_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Press the button in the window, and compute the cone absorptions from
            %    an OI in the database.
            %
            % Inputs:
            %    hObject   - The handle to menuAnComputeFromOI (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            btnComputeImage_Callback(hObject, eventdata, handles);
        end
        
        function menuPlotCone_Callback(app, hObject, eventdata, handles)
            % (Menu | Plot | Cone) Plot the cone
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Plot the Cone.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
        end
        
        function sliderMovieProgress_Callback(app, ~, ~, handles)
            % (Movie | Set Progress Bar) Set slider to point in timeline of movie
            %
            % Syntax:
            %   menuPlotVLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Set the slider to a specific time point on the bar.
            %
            % Inputs:
            %    hObject   - The handle to sliderMovieProgress (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            index = get(handles.popupImageType, 'Value');
            if index == 3, mov = handles.cMosaic.absorptions;
            elseif index == 5, mov = handles.cMosaic.current;
            end
            
            gam = get(handles.editGam, 'value');
            
            mov = ieScale(mov, 0, 1) .^ gam;
            mind = min(mov(:));
            maxd = max(mov(:));
            
            cnt = round(get(handles.sliderMovieProgress, 'Value'));
            assert(cnt <= size(mov, ndims(mov)), 'slider choice out of range');
            axes(handles.axes2);
            imagesc(mov(:, :, cnt));
            axis image;
            set(gca, 'xticklabel', '', 'yticklabel', '');
            caxis([mind maxd]);
            drawnow;
            set(handles.txtMovieFrame, 'string', cnt);
            
            % register right click menu
            c = uicontextmenu;
            handles.axes2.Children.UIContextMenu = c;
            uimenu(c, 'Label', 'hLine response', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'vLine response', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'hLine LMS', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'vLine LMS', 'Callback', @app.contextMenuPlot);
            uimenu(c, 'Label', 'time series', 'Callback', @app.contextMenuPlot);
        end
        
        function sliderMovieProgress_CreateFcn(app, hObject, eventdata, handles)
            % (Create | Slider Background) Set default slider background to black
            %
            % Syntax:
            %   sliderMovieProgress_CreateFcn(hObject, eventdata, handles)
            %
            % Description:
            %    Set the slider background to black after all attributes and parameters
            %    have been set.
            %
            % Inputs:
            %    hObject   - The handle to sliderMovieProgress (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Empty, handles are not created until after all of the
            %                CreateFcns have been called.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            if isequal(get(hObject, 'BackgroundColor'), ...
                    get(0, 'defaultUicontrolBackgroundColor'))
                set(hObject, 'BackgroundColor', [.9 .9 .9]);
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.coneMosaicWindow.CloseRequestFcn = 'close';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function coneMosaicWindow_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % (Window | Open) Initialize and open the Cone Mosaic Window
            %
            % Syntax:
            %   coneMosaicWindow_OpeningFcn(hObject, eventdata, handles, [varargin])
            %
            % Description:
            %    This function will initialize and open the cone mosaic window using
            %    the provided information
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %    varargin  - (Optional) Additional information for initializing the
            %                window, such as an existing cone mosaic.
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            %#ok<*DEFNU>
            %#ok<*INUSD>
            %#ok<*ST2NM>
            
            % check inputs
            if isempty(varargin) || ~isa(varargin{1}, 'coneMosaic')
                error('cone mosaic object required');
            end
            plotType = 'meanabsorptions';
            if length(varargin) > 1, plotType = varargin{2}; end
            
            % Choose default command line output for coneMosaicWindow
            handles.output = hObject;
            handles.cMosaic = varargin{1};
            handles.mov = [];  % absorption movie
            handles.curMov = [];  % photocurrent movie
            
            % Update handles structure
            guidata(hObject, handles);
            handles.cMosaic.hdl = hObject;
            
            % Adjust the database and bring this figure to the front
            vcSetFigureHandles('conemosaic', hObject, eventdata, handles);
            figure(hObject);
            
            % Set the popup default image selection to mean absorptions for when the
            % window opens.
            str = get(handles.popupImageType, 'String');
            for ii=1:numel(str), str{ii} = ieParamFormat(str{ii}); end
            
            % Refresh and move on
            coneMosaicGUIRefresh(app, hObject, eventdata, handles, plotType);
            
            % Set the font size based on the ISETBIO preferences
            ieFontInit(hObject);
            
            % Very important for good rendering speed
            set(hObject, 'Renderer', 'OpenGL')
        end

        % Button pushed function: btnComputeImage
        function btnComputeImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Button | Absorptions) Compute image from optics data - button press
            %
            % Syntax:
            %   btnComputeImage_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Function called at press of the button at the bottom of the window,
            %    which will compute the image from the provided optics data.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            oi = vcGetObject('OI');
            if isempty(oi) || isempty(oiGet(oi, 'photons'))
                warning('No optical image. Use ieAddObject(oi) to store.');
                return;
            end
            
            handles.cMosaic.compute(oi);
            handles.cMosaic.name = oiGet(oi, 'name');
            set(handles.popupImageType, 'Value', 2); % mean absorptions
            
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editCols
        function editCols_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Textbox) Change the value of the Columns text box
            %
            % Syntax:
            %   editCols_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Columns text box.
            %
            % Inputs:
            %    hObject   - The handle to editCols (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.cols = str2double(get(hObject, 'String'));
            menuEditClearData_Callback(hObject, eventdata, handles)
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editConeHeight
        function editConeHeight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Height) Modify the cone height
            %
            % Syntax:
            %   editConeHeight_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the cone height.
            %
            % Inputs:
            %    hObject   - The handle to editConeHeight (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            newHeight = 1e-6 * str2double(get(hObject, 'String'));
            if handles.cMosaic.pigment.height ~= newHeight
                handles.cMosaic.pigment.height = newHeight;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConeOpticalDensity
        function editConeOpticalDensity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Optical Density) Edit the cone optical density
            %
            % Syntax:
            %   menuPlotConeOpticalDensity_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Plot the cone optical density.
            %
            % Inputs:
            %    hObject   - The handle to coneOpticalDensity (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2num(get(hObject, 'String'));
            assert(numel(val) == 3, 'invalid input for optical density');
            
            if any(handles.cMosaic.pigment.opticalDensity(:) ~= val(:))
                handles.cMosaic.pigment.opticalDensity = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConePeakEfficiency
        function editConePeakEfficiency_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Peak Efficiency) Edit the Cone peak efficiency
            %
            % Syntax:
            %   editConePeakEfficiency_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the cone peak efficiency
            %
            % Inputs:
            %    hObject   - The handle to editConePeakEfficiency (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2num(get(hObject, 'String'));
            assert(numel(val) == 3, 'invalid input for peak efficiency');
            if any(handles.cMosaic.pigment.peakEfficiency(:) ~= val(:))
                handles.cMosaic.pigment.peakEfficiency = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editConeWidth
        function editConeWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Cone Width) Modify the width of an existing cone
            %
            % Syntax:
            %   editConeWidth_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the cone width
            %
            % Inputs:
            %    hObject   - The handle to editConeWidth (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            newWidth = 1e-6 * str2double(get(hObject, 'String'));
            
            if handles.cMosaic.pigment.width ~= newWidth
                handles.cMosaic.pigment.width = newWidth;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editExpTime
        function editExpTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Textbox) Change the value of the integration time text box
            %
            % Syntax:
            %   editExpTime_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Integration Time text box.
            %
            % Inputs:
            %    hObject   - The handle to editExpTime (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.integrationTime = 1e-3 * ...
                str2double(get(hObject, 'String'));
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editGam
        function editGam_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | ) Text book Gamm
            %
            % Syntax:
            %   menuPlotCone_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    "Text book Gamm" - edit
            %
            % Inputs:
            %    hObject   - The handle to editGam (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            set(handles.editGam, 'value', str2double(get(handles.editGam, 'string')));
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Value changed function: editKLMS
        function editKLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | KLMS) Modify the KLMS
            %
            % Syntax:
            %   editKLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the KLMS
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            density = str2num(get(handles.editKLMS, 'String'));
            assert(numel(density) == 4, 'invalid input');
            
            density = density / sum(density);
            handles.cMosaic.spatialDensity = density;
            menuEditClearData_Callback(hObject, eventdata, handles);
        end

        % Value changed function: editMacularDensity
        function editMacularDensity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Macular Density) Edit the existing macular density
            %
            % Syntax:
            %   editMacularDensity_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Edit the existing macular density
            %
            % Inputs:
            %    hObject   - The handle to editMacularDensity (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            val = str2double(get(hObject, 'String'));
            if handles.cMosaic.macular.density ~= val
                handles.cMosaic.macular.density = val;
                menuEditClearData_Callback(hObject, eventdata, handles);
            end
        end

        % Value changed function: editRows
        function editRows_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Edit | Textbox) Change the value of the Rows text box
            %
            % Syntax:
            %   editRows_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Change the value of the Rows text box.
            %
            % Inputs:
            %    hObject   - The handle to editRows (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.rows = str2double(get(hObject, 'String'));
            menuEditClearData_Callback(hObject, eventdata, handles)
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuAppNotes
        function menuAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Help | Web Documentation) Call web documentation
            %
            % Syntax:
            %   menuAppNotes_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Open the web documentation
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            web('https://github.com/isetbio/isetbio/wiki', '-browser');
        end

        % Menu selected function: menuConePhotocurrentNoise
        function menuConePhotocurrentNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Photocurrent) Toggle photocurrent noise
            %
            % Syntax:
            %   menuConePhotocurrentNoise_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Toggle the photocurrent noise setting. The toggle command produces the
            %    following changes in the noise setting:
            %       Random -> None
            %       Frozen -> Random
            %       None   -> Frozen
            %
            % Inputs:
            %    hObject   - The handle to menuConePhotocurrentNoise (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            % Notes:
            %    * Also executes computeCurrent
            %
            
            % set(handles.btnPlayPause, 'Value', 0);  % Turn off any movie.
            
            % Flip from whatever state to the other
            switch handles.cMosaic.os.noiseFlag
                case 'random'
                    handles.cMosaic.os.noiseFlag = 'none';
                case 'frozen'
                    handles.cMosaic.os.noiseFlag = 'random';
                case 'none'
                    handles.cMosaic.os.noiseFlag = 'frozen';
            end
            
            % We used to use this. But now, I think it should always be off.
            handles.menuConePhotocurrentNoise.Checked = 'off';
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuConesAbsorptions
        function menuConesAbsorptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Absorptions) Computes absorptions
            %
            % Syntax:
            %   menuConesAbsorptions_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Load the current oi to compute the absorptions. If no oi is selected,
            %    the function will complain.
            %
            % Inputs:
            %    hObject   - The handle to menuConesAbsorptions (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            oi = vcGetObject('OI');
            if isempty(oi) || isempty(oiGet(oi, 'photons'))
                warning('No optical image. Use ieAddObject(oi) to store.');
                return;
            end
            
            fprintf('Calculating with optical image %s\n', oiGet(oi, 'name'));
            handles.cMosaic.compute(oi);
            handles.cMosaic.name = oiGet(oi, 'name');
            set(handles.popupImageType, 'Value', 2); % mean absorptions
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuConesGenerateEM
        function menuConesGenerateEM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Eye Movement) Generates eye movements
            %
            % Syntax:
            %   menuConesGenerateEM_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Generate the cone's eye movements
            %
            % Inputs:
            %    hObject   - The handle to menuConesGenerateEM (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            str = ieReadString('Number of frames', '500');
            if ~isempty(str)
                % Changed by BW on Nov 12, 2019.  The path was not randomized when
                % called from the pulldown.  This rSeed calls shuffle.
                % Must check with others about this!
                handles.cMosaic.emGenSequence(str2double(str),'rSeed',[]);
                menuEditClearData_Callback(hObject, eventdata, handles);
                set(handles.popupImageType, 'Value', 2); % mean absorptions
                coneMosaicGUIRefresh(app, hObject, eventdata, handles);
            end
        end

        % Menu selected function: menuConesPhotocurrent
        function menuConesPhotocurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Cones | Photocurrent) Compute photocurrent
            %
            % Syntax:
            %   menuConePhotocurrent_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Compute the photocurrent
            %
            % Inputs:
            %    hObject   - The handle to menuConePhotocurrent (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.computeCurrent;
            set(handles.popupImageType, 'Value', 4); % mean current
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuEditClearData
        function menuEditClearData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Clear Data) Clear the data
            %
            % Syntax:
            %   menuEditClearData_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Clear the data
            %
            % Inputs:
            %    hObject   - The handle to menuEditClearData (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.clearData();  % Clear absorptions and current
            
            % mosaicImage is the colorful cone mosaic image.
            % We used to save the absorption and current movie.  But no more.  It
            % doesn't seem to save any time.
            uData = get(handles.axes2,'UserData');
            if isfield(uData,'mosaicImage')
                uData.mosaicImage = [];
                set(handles.axes2,'UserData',uData);
            end
            
            guidata(hObject, handles); % Put back the modified handles
            
            coneMosaicGUIRefresh(app, hObject, eventdata, handles); % Update the window
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Font Size) Modify the font size
            %
            % Syntax:
            %   menuEditFontSize_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Modify the font size
            %
            % Inputs:
            %    hObject   - The handle to menuEditFontSize (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            ieFontSizeSet(handles.coneMosaicWindow);
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Edit | Rename) Rename an existing object
            %
            % Syntax:
            %   menuEditName_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Rename an existing object
            %
            % Inputs:
            %    hObject   - The handle to menuPlotCone (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            str = ieReadString('New name', handles.cMosaic.name);
            if ~isempty(str), handles.cMosaic.name = str; end
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | File) Close the window
            %
            % Syntax:
            %   menuFileClose_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Close the window (delete the handle to the window)
            %
            % Inputs:
            %    hObject   - The handle to menuFileClose (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            delete(handles.coneMosaicWindow);
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | File | Refresh) Refresh button
            %
            % Syntax:
            %   menuFileRefresh_Callback(~, ~, handles)
            %
            % Description:
            %    Refresh button in file menu
            %
            % Inputs:
            %    hObject   - The handle to menuFileRefresh (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end

        % Menu selected function: menuPlotConeAbsorptance
        function menuPlotConeAbsorptance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Cone Absorptance) Plot cone absorptance
            %
            % Syntax:
            %   menuPlotConeAbsorptance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the cone absorptance.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotConeAbsorptance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('cone fundamentals');
        end

        % Menu selected function: menuPlotEMPath
        function menuPlotEMPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | EMPath) Plot Eye Movement Path
            %
            % Syntax:
            %   menuPlotEMPath_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the eye movement path.
            %
            % Inputs:
            %    hObject   - The handle to menuPlotEMPath (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('eye movement path');
        end

        % Menu selected function: menuPlotHLineLMS
        function menuPlotHLineLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | H Line LMS Absorptions) Plot H LMS cone absorptions
            %
            % Syntax:
            %   menuPlotHLineLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the L, M, and
            %    S absorptions per frame by cone horizontal positions in a new window
            %    containing a vertical subplot for each cone type.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app, hObject, []);
        end

        % Menu selected function: menuPlotHLine
        function menuPlotHLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | H Line Response) Plot H Cone responses from a point
            %
            % Syntax:
            %   menuPlotHLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the
            %    horizontal cone responses per frame in a new window.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app, hObject, []);
        end

        % Menu selected function: menuPlotMacularAbsorbance
        function menuPlotMacularAbsorbance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular absorbance
            %
            % Syntax:
            %   menuPlotEMPath_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular absorbance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularAbsorbance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular absorbance');
        end

        % Menu selected function: menuPlotMacularAbsorptance
        function menuPlotMacularAbsorptance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular absorptance
            %
            % Syntax:
            %   menuPlotMacularAbsorptance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular absorptance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularAbsorptance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular absorptance');
        end

        % Menu selected function: menuPlotMacularTransmittance
        function menuPlotMacularTransmittance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Macular) Plot the macular transmittance
            %
            % Syntax:
            %   menuPlotMacularTransmittance_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the macular transmittance
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMacularTransmittance (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('macular transmittance');
        end

        % Menu selected function: menuPlotMosaicConeMosaic
        function menuPlotMosaicConeMosaic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Mosaic ) Plot the Cone Mosaic from the Mosaic menu
            %
            % Syntax:
            %   menuPlotMosaicConeMosaic_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the cone mosaic from the Mosaic menu
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicConeMosaic (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('cone mosaic', ...
                'showCorrespondingRectangularMosaicInstead', false);
        end

        % Menu selected function: menuPlotMosaicMeanAbsorptions
        function menuPlotMosaicMeanAbsorptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Mosaic) Plot the mean absorptions from Mosaic
            %
            % Syntax:
            %   menuPlotMosaicMeanAbsorptions_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the mean absorptions from the Mosaic menu under Plot
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicMeanAbsorptions (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('mean absorptions');
        end

        % Menu selected function: menuPlotMosaicMeanCurrent
        function menuPlotMosaicMeanCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Mosaic) Plot Mean Current from Mosaic
            %
            % Syntax:
            %   menuPlotMosaicMeanCurrent_Callback(~, ~, handles)
            %
            % Description:
            %    Plot the mean current from the mosaic menu
            %
            % Inputs:
            %    hObject   - The handle to menuPlotMosaicMeanCurrent (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            handles.cMosaic.plot('mean current');
        end

        % Menu selected function: menuPlotTimeSeries
        function menuPlotTimeSeries_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | Time Series) A red circle deposited on cone mosaic window.
            %
            % Syntax:
            %	menuPlotTimeSeries_Callback(hObject, ~, handles)
            %
            % Description:
            %    Plot a single point in the window and create a graph of absorptions
            %    per frame over time.
            %
            % Inputs:
            %    hObject - The handle to the time series
            %    handles - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            % set(handles.btnPlayPause, 'Value', 0);  % Pause the movie
            contextMenuPlot(app, hObject, []);
        end

        % Menu selected function: menuPlotVLineLMS
        function menuPlotVLineLMS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | V Line LMS Absorptions) Plot V LMS cone absorptions
            %
            % Syntax:
            %   menuPlotVLineLMS_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the L, M, and
            %    S absorptions per frame by cone vertical positions in a new window
            %    containing a vertical subplot for each cone type.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app, hObject, []);
        end

        % Menu selected function: menuPlotVLine
        function menuPlotVLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Menu | Plot | V Line Response) Plot V Cone responses from a point
            %
            % Syntax:
            %   menuPlotVLine_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    From a single point in the cone mosaic window, calculate the vertical
            %    cone responses per frame in a new window.
            %
            % Inputs:
            %    hObject   - The handle to the time series
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            contextMenuPlot(app, hObject, []);
        end

        % Value changed function: popupImageType
        function popupImageType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % (Image | Refresh Image) Refresh image object
            %
            % Syntax:
            %   popupImageType_Callback(hObject, eventdata, handles)
            %
            % Description:
            %    Refresh the image object
            %
            % Inputs:
            %    hObject   - The handle to popupImageType (see GCBO)
            %    eventdata - reserved - to be defined in a future version of MATLAB
            %    handles   - Structure with handles and user data (see GUIDATA)
            %
            % Outputs:
            %    None.
            %
            % Optional key/value pairs:
            %    None.
            %
            
            % Stop any movies.
            % set(handles.btnPlayPause, 'Value', 0);  % Turn off the movie.
            
            % Refresh.
            coneMosaicGUIRefresh(app, hObject, eventdata, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.coneMosaicWindow = uifigure('Visible', 'off');
            app.coneMosaicWindow.Name = 'Cone Mosaic';
            app.coneMosaicWindow.Tag = 'coneMosaicWindow';
            app.menuFile = uimenu(app.coneMosaicWindow);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.coneMosaicWindow);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = 'Rename';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditClearData = uimenu(app.menuEdit);
            app.menuEditClearData.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearData_Callback, true);
            app.menuEditClearData.Text = 'Clear data';
            app.menuEditClearData.Tag = 'menuEditClearData';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuPlot = uimenu(app.coneMosaicWindow);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlotMacular = uimenu(app.menuPlot);
            app.menuPlotMacular.Text = 'Macular';
            app.menuPlotMacular.Tag = 'menuPlotMacular';
            app.menuPlotMacularTransmittance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularTransmittance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularTransmittance_Callback, true);
            app.menuPlotMacularTransmittance.Text = 'Transmittance';
            app.menuPlotMacularTransmittance.Tag = 'menuPlotMacularTransmittance';
            app.menuPlotMacularAbsorptance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularAbsorptance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularAbsorptance_Callback, true);
            app.menuPlotMacularAbsorptance.Text = 'Absorptance';
            app.menuPlotMacularAbsorptance.Tag = 'menuPlotMacularAbsorptance';
            app.menuPlotMacularAbsorbance = uimenu(app.menuPlotMacular);
            app.menuPlotMacularAbsorbance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMacularAbsorbance_Callback, true);
            app.menuPlotMacularAbsorbance.Text = 'Absorbance';
            app.menuPlotMacularAbsorbance.Tag = 'menuPlotMacularAbsorbance';
            app.menuPlotConeAbsorptance = uimenu(app.menuPlot);
            app.menuPlotConeAbsorptance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotConeAbsorptance_Callback, true);
            app.menuPlotConeAbsorptance.Text = 'Cone absorptance';
            app.menuPlotConeAbsorptance.Tag = 'menuPlotConeAbsorptance';
            app.menuPlotMosaic = uimenu(app.menuPlot);
            app.menuPlotMosaic.Text = 'Mosaic';
            app.menuPlotMosaic.Tag = 'menuPlotMosaic';
            app.menuPlotMosaicConeMosaic = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicConeMosaic.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicConeMosaic_Callback, true);
            app.menuPlotMosaicConeMosaic.Text = 'Cone mosaic';
            app.menuPlotMosaicConeMosaic.Tag = 'menuPlotMosaicConeMosaic';
            app.menuPlotMosaicMeanAbsorptions = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicMeanAbsorptions.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicMeanAbsorptions_Callback, true);
            app.menuPlotMosaicMeanAbsorptions.Text = 'Mean absorptions';
            app.menuPlotMosaicMeanAbsorptions.Tag = 'menuPlotMosaicMeanAbsorptions';
            app.menuPlotMosaicMeanCurrent = uimenu(app.menuPlotMosaic);
            app.menuPlotMosaicMeanCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMosaicMeanCurrent_Callback, true);
            app.menuPlotMosaicMeanCurrent.Text = 'Mean current';
            app.menuPlotMosaicMeanCurrent.Tag = 'menuPlotMosaicMeanCurrent';
            app.menuPlotEMPath = uimenu(app.menuPlot);
            app.menuPlotEMPath.MenuSelectedFcn = createCallbackFcn(app, @menuPlotEMPath_Callback, true);
            app.menuPlotEMPath.Text = 'Eye movement path';
            app.menuPlotEMPath.Tag = 'menuPlotEMPath';
            app.menuPlotHLine = uimenu(app.menuPlot);
            app.menuPlotHLine.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHLine_Callback, true);
            app.menuPlotHLine.Text = 'hLine response';
            app.menuPlotHLine.Tag = 'menuPlotHLine';
            app.menuPlotVLine = uimenu(app.menuPlot);
            app.menuPlotVLine.MenuSelectedFcn = createCallbackFcn(app, @menuPlotVLine_Callback, true);
            app.menuPlotVLine.Text = 'vLine response';
            app.menuPlotVLine.Tag = 'menuPlotVLine';
            app.menuPlotHLineLMS = uimenu(app.menuPlot);
            app.menuPlotHLineLMS.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHLineLMS_Callback, true);
            app.menuPlotHLineLMS.Text = 'hLine LMS';
            app.menuPlotHLineLMS.Tag = 'menuPlotHLineLMS';
            app.menuPlotVLineLMS = uimenu(app.menuPlot);
            app.menuPlotVLineLMS.MenuSelectedFcn = createCallbackFcn(app, @menuPlotVLineLMS_Callback, true);
            app.menuPlotVLineLMS.Text = 'vLine LMS';
            app.menuPlotVLineLMS.Tag = 'menuPlotVLineLMS';
            app.menuPlotTimeSeries = uimenu(app.menuPlot);
            app.menuPlotTimeSeries.MenuSelectedFcn = createCallbackFcn(app, @menuPlotTimeSeries_Callback, true);
            app.menuPlotTimeSeries.Text = 'time series';
            app.menuPlotTimeSeries.Tag = 'menuPlotTimeSeries';
            app.menuCones = uimenu(app.coneMosaicWindow);
            app.menuCones.Text = 'Cones';
            app.menuCones.Tag = 'menuCones';
            app.menuConesGenerateEM = uimenu(app.menuCones);
            app.menuConesGenerateEM.MenuSelectedFcn = createCallbackFcn(app, @menuConesGenerateEM_Callback, true);
            app.menuConesGenerateEM.Text = 'Generate eye movement';
            app.menuConesGenerateEM.Tag = 'menuConesGenerateEM';
            app.menuConesAbsorptions = uimenu(app.menuCones);
            app.menuConesAbsorptions.MenuSelectedFcn = createCallbackFcn(app, @menuConesAbsorptions_Callback, true);
            app.menuConesAbsorptions.Text = 'Compute absorptions';
            app.menuConesAbsorptions.Tag = 'menuConesAbsorptions';
            app.menuConesPhotocurrent = uimenu(app.menuCones);
            app.menuConesPhotocurrent.MenuSelectedFcn = createCallbackFcn(app, @menuConesPhotocurrent_Callback, true);
            app.menuConesPhotocurrent.Text = 'Compute photocurrent';
            app.menuConesPhotocurrent.Tag = 'menuConesPhotocurrent';
            app.menuConePhotocurrentNoise = uimenu(app.menuCones);
            app.menuConePhotocurrentNoise.MenuSelectedFcn = createCallbackFcn(app, @menuConePhotocurrentNoise_Callback, true);
            app.menuConePhotocurrentNoise.Text = 'Toggle photocurrent noise';
            app.menuConePhotocurrentNoise.Tag = 'menuConePhotocurrentNoise';
            app.menuHelp = uimenu(app.coneMosaicWindow);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuAppNotes = uimenu(app.menuHelp);
            app.menuAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuAppNotes_Callback, true);
            app.menuAppNotes.Text = 'Documentation (web)';
            app.menuAppNotes.Tag = 'menuAppNotes';
            app.txtConsistency = uilabel(app.coneMosaicWindow);
            app.txtConsistency.Tag = 'txtConsistency';
            app.txtConsistency.Text = '';
            app.txtMosaic = uilabel(app.coneMosaicWindow);
            app.txtMosaic.Tag = 'txtMosaic';
            app.txtMosaic.Text = 'Description';
            app.txtOS = uilabel(app.coneMosaicWindow);
            app.txtOS.Tag = 'txtOS';
            app.txtOS.Text = 'Outer segment';
            app.MosaicpropertiesPanel = uipanel(app.coneMosaicWindow);
            app.MosaicpropertiesPanel.Title = 'Mosaic properties';
            app.editRows = uieditfield(app.MosaicpropertiesPanel, 'text');
            app.editRows.ValueChangedFcn = createCallbackFcn(app, @editRows_Callback, true);
            app.editRows.Tag = 'editRows';
            app.editRows.Tooltip = 'set cone mosaic rows';
            app.editRows.Value = '72';
            app.editCols = uieditfield(app.MosaicpropertiesPanel, 'text');
            app.editCols.ValueChangedFcn = createCallbackFcn(app, @editCols_Callback, true);
            app.editCols.Tag = 'editCols';
            app.editCols.Tooltip = 'set cone mosaic cols';
            app.editCols.Value = '88';
            app.txtMosaicSize = uilabel(app.MosaicpropertiesPanel);
            app.txtMosaicSize.Tag = 'txtMosaicSize';
            app.txtMosaicSize.Tooltip = 'Adjust the number of pixels in the ISA';
            app.txtMosaicSize.Text = 'Row Col';
            app.editExpTime = uieditfield(app.MosaicpropertiesPanel, 'text');
            app.editExpTime.ValueChangedFcn = createCallbackFcn(app, @editExpTime_Callback, true);
            app.editExpTime.Tag = 'editExpTime';
            app.editExpTime.Tooltip = 'Set (central) exposure time ';
            app.editExpTime.Value = '0';
            app.txtExposureUnits = uilabel(app.MosaicpropertiesPanel);
            app.txtExposureUnits.Tag = 'txtExposureUnits';
            app.txtExposureUnits.Text = '(ms)';
            app.txtIntegration = uilabel(app.MosaicpropertiesPanel);
            app.txtIntegration.Tag = 'txtIntegration';
            app.txtIntegration.Text = 'Integration';
            app.txtEccentricity = uilabel(app.MosaicpropertiesPanel);
            app.txtEccentricity.Tag = 'txtEccentricity';
            app.txtEccentricity.Tooltip = 'Deg of angle re: fovea';
            app.txtEccentricity.Text = 'Eccentricity';
            app.txtKLMSRatio = uilabel(app.MosaicpropertiesPanel);
            app.txtKLMSRatio.Tag = 'txtKLMSRatio';
            app.txtKLMSRatio.Text = 'Blank-LMS';
            app.editKLMS = uieditfield(app.MosaicpropertiesPanel, 'text');
            app.editKLMS.ValueChangedFcn = createCallbackFcn(app, @editKLMS_Callback, true);
            app.editKLMS.Tag = 'editKLMS';
            app.editKLMS.Value = '[.1 .5 .3 .1]';
            app.editEccentricity = uieditfield(app.MosaicpropertiesPanel, 'text');
            app.editEccentricity.Tag = 'editEccentricity';
            app.editEccentricity.Value = '0';
            app.RenderPanel = uipanel(app.coneMosaicWindow);
            app.RenderPanel.Title = 'Render';
            app.editGam = uieditfield(app.RenderPanel, 'text');
            app.editGam.ValueChangedFcn = createCallbackFcn(app, @editGam_Callback, true);
            app.editGam.Tag = 'editGam';
            app.editGam.Tooltip = 'Set display gamma - data are not affected';
            app.editGam.Value = '1';
            app.txtDisplayGamma = uilabel(app.RenderPanel);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.btnComputeImage = uibutton(app.RenderPanel, 'push');
            app.btnComputeImage.ButtonPushedFcn = createCallbackFcn(app, @btnComputeImage_Callback, true);
            app.btnComputeImage.Tag = 'btnComputeImage';
            app.btnComputeImage.Tooltip = 'Compute cone absorptions';
            app.btnComputeImage.Text = 'Compute cone absorptions';
            app.MosaicnamePanel = uipanel(app.coneMosaicWindow);
            app.MosaicnamePanel.Title = 'Mosaic name';
            app.txtMessage = uilabel(app.MosaicnamePanel);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.popupImageType = uidropdown(app.MosaicnamePanel);
            app.popupImageType.Items = {'Mean absorptions', 'Cone mosaic'};
            app.popupImageType.ValueChangedFcn = createCallbackFcn(app, @popupImageType_Callback, true);
            app.popupImageType.Tag = 'popupImageType';
            app.popupImageType.Value = 'Mean absorptions';
            app.axes2 = uiaxes(app.MosaicnamePanel);
            app.axes2.XLim = [0 1];
            app.axes2.YLim = [0 1];
            app.axes2.ZLim = [0 1];
            app.axes2.CLim = [0 1];
            app.axes2.ALim = [0 1];
            app.axes2.XTickLabel = '';
            app.axes2.YTick = zeros(1,0);
            app.axes2.YTickLabel = '';
            app.axes2.ZTick = [0 0.5 1];
            app.axes2.ZTickLabel = '';
            app.axes2.Tag = 'axes2';
            app.ConepropertiesPanel = uipanel(app.coneMosaicWindow);
            app.ConepropertiesPanel.Title = 'Cone properties';
            app.text46 = uilabel(app.ConepropertiesPanel);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Cone size (um)';
            app.editConeWidth = uieditfield(app.ConepropertiesPanel, 'text');
            app.editConeWidth.ValueChangedFcn = createCallbackFcn(app, @editConeWidth_Callback, true);
            app.editConeWidth.Tag = 'editConeWidth';
            app.editConeWidth.Tooltip = 'set cone photodetector width';
            app.editConeWidth.Value = '2';
            app.editConeHeight = uieditfield(app.ConepropertiesPanel, 'text');
            app.editConeHeight.ValueChangedFcn = createCallbackFcn(app, @editConeHeight_Callback, true);
            app.editConeHeight.Tag = 'editConeHeight';
            app.editConeHeight.Tooltip = 'cone photodector height';
            app.editConeHeight.Value = '2';
            app.textConePigmentDensity = uilabel(app.ConepropertiesPanel);
            app.textConePigmentDensity.Tag = 'textConePigmentDensity';
            app.textConePigmentDensity.Text = 'Pigment density';
            app.editConeOpticalDensity = uieditfield(app.ConepropertiesPanel, 'text');
            app.editConeOpticalDensity.ValueChangedFcn = createCallbackFcn(app, @editConeOpticalDensity_Callback, true);
            app.editConeOpticalDensity.Tag = 'editConeOpticalDensity';
            app.textConePeakEfficiency = uilabel(app.ConepropertiesPanel);
            app.textConePeakEfficiency.Tag = 'textConePeakEfficiency';
            app.textConePeakEfficiency.Text = 'Peak';
            app.editConePeakEfficiency = uieditfield(app.ConepropertiesPanel, 'text');
            app.editConePeakEfficiency.ValueChangedFcn = createCallbackFcn(app, @editConePeakEfficiency_Callback, true);
            app.editConePeakEfficiency.Tag = 'editConePeakEfficiency';
            app.textMacularDensity = uilabel(app.ConepropertiesPanel);
            app.textMacularDensity.Tag = 'textMacularDensity';
            app.textMacularDensity.Text = 'Macular density';
            app.editMacularDensity = uieditfield(app.ConepropertiesPanel, 'text');
            app.editMacularDensity.ValueChangedFcn = createCallbackFcn(app, @editMacularDensity_Callback, true);
            app.editMacularDensity.Tag = 'editMacularDensity';
            % Show the figure after all components are created
            app.coneMosaicWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coneMosaicWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.coneMosaicWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)coneMosaicWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.coneMosaicWindow)
        end
    end
end